classdef drivecyclecreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LaptimesecconsideringpowerandenergyEditField  matlab.ui.control.NumericEditField
        LaptimesecconsideringpowerandenergyEditFieldLabel  matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        DrivecycleButton                matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        LaptimesecconsideringtorquecurveEditField  matlab.ui.control.NumericEditField
        LaptimesecconsideringtorquecurveEditFieldLabel  matlab.ui.control.Label
        CalculateFDRButton              matlab.ui.control.Button
        TopSpeedmphEditField            matlab.ui.control.NumericEditField
        TopSpeedmphEditFieldLabel       matlab.ui.control.Label
        FinalDriveRatioEditField        matlab.ui.control.NumericEditField
        FinalDriveRatioLabel            matlab.ui.control.Label
        EnginePowerkWEditField          matlab.ui.control.EditField
        EnginePowerkWEditFieldLabel     matlab.ui.control.Label
        GradedegEditField               matlab.ui.control.NumericEditField
        GradedegEditFieldLabel          matlab.ui.control.Label
        MaxDischargePowerkWEditField    matlab.ui.control.EditField
        MaxDischargePowerkWEditFieldLabel  matlab.ui.control.Label
        wheelbaseEditField              matlab.ui.control.NumericEditField
        CoGheightinEditFieldLabel_2     matlab.ui.control.Label
        ContinousChargePowerkWEditField  matlab.ui.control.EditField
        ContinousChargePowerkWEditFieldLabel  matlab.ui.control.Label
        CoGheightinEditField            matlab.ui.control.NumericEditField
        CoGheightinEditFieldLabel       matlab.ui.control.Label
        TimeforMaxChargingsecEditField  matlab.ui.control.EditField
        TimeforMaxChargingsecEditFieldLabel  matlab.ui.control.Label
        DistribuitionRearEditField      matlab.ui.control.NumericEditField
        DistribuitionRearEditFieldLabel  matlab.ui.control.Label
        MaxChargePowerkWEditField       matlab.ui.control.EditField
        MaxChargePowerkWEditFieldLabel  matlab.ui.control.Label
        MasskgEditField                 matlab.ui.control.NumericEditField
        MasskgEditFieldLabel            matlab.ui.control.Label
        BatteryMaxCapacitykWhEditField  matlab.ui.control.EditField
        BatteryMaxCapacitykWhEditFieldLabel  matlab.ui.control.Label
        Assuming5050DistCoG40inwheelbase130inGrade40Label  matlab.ui.control.Label
        InputsPanel                     matlab.ui.container.Panel
        ContDchgEditField               matlab.ui.control.EditField
        ContDchgEditFieldLabel          matlab.ui.control.Label
        BatteryMaxCurrentEditField      matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        BatteryMaxCurrentEditFieldLabel  matlab.ui.control.Label
        CurrentatMaxTorqueEditField     matlab.ui.control.NumericEditField
        CurrentatMaxTorqueEditFieldLabel  matlab.ui.control.Label
        MaxMotorTorqueEditField         matlab.ui.control.NumericEditField
        MaxMotorTorqueEditFieldLabel    matlab.ui.control.Label
        GenEffEditField                 matlab.ui.control.NumericEditField
        GenEffEditFieldLabel            matlab.ui.control.Label
    end


    properties (Access = public)
        newVertGrade % Description
        newgSpeed
        newTime
        fr_new
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros(5,numel(app.UITable.ColumnName));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UITable.Data(end+1,:) = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.UITable.Data(end,:) = [];
        end

        % Button pushed function: CalculateFDRButton
        function CalculateFDRButtonPushed(app, event)
            wd = app.DistribuitionRearEditField.Value;

            theta = deg2rad(app.GradedegEditField.Value);
            wheelbase = app.wheelbaseEditField.Value.*25.4./1000;
            cgHeight = (app.CoGheightinEditField.Value.*25.4./1000)';
            mass = app.MasskgEditField.Value;
            g = 9.81;
            acc = 0;
            speed = 0;   %in m/s .*(5./18);
            rho = 1.225;
            cd = 0.4;
            af = 5;
            mu = 1.2;
            x=app.DistribuitionRearEditField.Value./100;
            a = wheelbase.*(x);
            b = wheelbase.*(1-x);
            wF = (mass/(a+b)).*(b*g*cos(theta) - cgHeight.*g*sin(theta) - cgHeight.*acc);
            wR = (mass/(a+b)).*(a*g*cos(theta) + cgHeight.*g*sin(theta) + cgHeight.*acc);
            perc_wF = wF./(mass.*g.*cos(theta));
            perc_wR = wR./(mass.*g.*cos(theta));
            tractForce = mass.*g.*sin(theta) + 0.25.*mass.*g.*cos(theta) + 0.5.*rho.*cd.*af.*power(speed,2) + mass.*acc;
            front_tractionlimit = mu.*wF./1000;
            rear_tractionlimit = mu.*wR./1000;
            tractRear = tractForce.*perc_wR;   % N
            tractFront = tractForce.*perc_wF;  % N
            rearTorq = tractRear.*(0.5)/2;      % Nm
            frontTorq = tractFront.*(0.5)/2;
            updatedTorq = app.BatteryMaxCurrentEditField.Value.*(max(app.UITable.Data(:,2))./app.CurrentatMaxTorqueEditField.Value);
            fdr = rearTorq./updatedTorq;
            app.FinalDriveRatioEditField.Value = fdr;
            motRPM = app.UITable.Data(:,1);
            motTorq = app.UITable.Data(:,2).*(app.BatteryMaxCurrentEditField.Value./app.CurrentatMaxTorqueEditField.Value);
            maxMotRPM = max(motRPM);
            topSpeed = maxMotRPM.*2.*0.5.*pi./60./fdr.*(18/5/1.6);
            app.TopSpeedmphEditField.Value = topSpeed;

            motTorq
            % Creating the drivecycle for the new FDR

            data = load("Copy_of_KoH_4400_Feb_2021_CONFIDENTIAL.mat").data.KoH4400Feb2021CONFIDENTIAL1;
            time = data(:,1);
            gSpeed = data(:,6).*(5./18);
            gpsAlt = data(:,42)./3.281;
            gpsLat = data(:,43);
            gpsLon = data(:,44);
            fr = 0.25;
            fr_new = data(:,412);
            fr_new(29688) = 0.03;
            Cd = 0.4;
            Af = 5;
            rho_air = 1.224;
            acc = diff(gSpeed);
            origin = [gpsLat(1),gpsLon(1),gpsAlt(1)];
            [gpsX,gpsY,gpsZ] = latlon2local(gpsLat,gpsLon,gpsAlt,origin);
            % Limiting Acceleration Noise
            acc(find(abs(acc)>=9.8*1.2))=1.2.*9.8;

            % Calculating the grade
            for i = 2:(length(gpsX)-1)
                dist(i) = sqrt(((gpsX(i)-gpsX(i-1)).^2) + ((gpsY(i)-gpsY(i-1)).^2));
                if dist(i)>40
                    dist(i) = dist(i-2);
                end
            end

            for i = 1:(length(gpsLat))
                if gpsLat(i)==0 || gpsLon(i)==0
                    gpsLat(i)=gpsLat(i-1);
                    gpsLon(i) = gpsLon(i-1);
                end
            end
            [gpsX,gpsY,gpsZ] = latlon2local(gpsLat,gpsLon,gpsAlt,origin);
            vertGrade = atand(diff(gpsZ)./dist');
            vertGrade(find(abs(vertGrade)>=rad2deg(theta)))=rad2deg(theta);
            vertGrade = medfilt1(vertGrade,10);
            vertGrade = deg2rad(vertGrade);
            mgu_rpm = motRPM;
            mgu_max_torq = motTorq;
            vehSpeed = gSpeed;
            timeDelta = ones(length(vehSpeed),1);
            fd_ratio = fdr;
            distance = vehSpeed.*1 + 0.5.*[acc;0].*1;
            distanceRef = distance;
            maxrpm = maxMotRPM;
            j =4192;
            r=1;
            for i = 4192:length(vehSpeed)-1

                if j >= 4750
                    i;
                end
                mot_rpm = vehSpeed(j)./((1.*2.*pi./60./fd_ratio(r)).*0.5);
                if vehSpeed(j+1)>=(maxrpm.*2.*pi./60./fd_ratio(r)).*0.5
                    vehSpeed(j+1) = (maxrpm.*2.*pi./60./fd_ratio(r)).*0.5;
                    mot_rpm = vehSpeed(j+1)./((1.*2.*pi./60./fd_ratio(r)).*0.5);
                    force1 = interp1(mgu_rpm,mgu_max_torq,mot_rpm).*4.*fd_ratio(r)./0.5;
                    newAcc(j) =  min(0.7.*9.81,(force1 - (fr_new(j).*mass.*g.*cos(vertGrade(j)) + 0.5.*Cd.*Af.*power(vehSpeed((j)),2).*rho_air + mass.*g.*sin(vertGrade(j))))./mass);
                    t1 = (vehSpeed(j+1)-vehSpeed(j))./newAcc(j);
                    s1 = (vehSpeed(j+1).^2-vehSpeed(j).^2)./(2.*newAcc(j));
                    t2 = (distance(j)-s1)./vehSpeed(j+1);
                    s2 = distance(j)-s1;
                    timeDelta(j+2:end+1) = timeDelta(j+1:end);
                    vehSpeed(j+2:end+1) = vehSpeed(j+1:end);
                    vertGrade(j+2:end+1) = vertGrade(j+1:end);
                    distance(j+2:end+1) = distance(j+1:end);
                    fr_new(j+2:end+1) = fr_new(j+1:end);
                    fr_new(j+1) = fr_new(j);
                    timeDelta(j) = t1;
                    timeDelta(j+1) = t2;
                    vehSpeed(j+1) = (maxrpm.*2.*pi./60./fd_ratio(r)).*0.5;
                    vertGrade(j+1) = vertGrade(j);
                    distance(j) = s1;
                    distance(j+1) = s2;
                    %             check(i,1) = timeRef(i) - (t1+t2);
                    %             check(i,2) = distanceRef(i) - s1-s2;
                    if vehSpeed(j+3)<(maxrpm.*2.*pi./60./fd_ratio(r)).*0.5
                        newAcc(j+2) = (vehSpeed(j+3).^2-vehSpeed(j+2).^2)./(2.*distanceRef(i+1));
                        syms t;
                        eqn = distanceRef(i+1)==vehSpeed(j+2).*t + 0.5*newAcc(j+2)*t.^2;
                        soln = double(solve(eqn));
                        timeDelta(j+2) = min(soln(find(soln>0)));
                    end
                    j=j+1;
                elseif vehSpeed(j+1)>5 && vehSpeed(j+1)>vehSpeed(j)
                    force1 = interp1(mgu_rpm,mgu_max_torq,mot_rpm).*4.*fd_ratio(r)./0.5;
                    newAcc(j) =  min(0.7.*9.81,(force1 - (fr_new(j).*mass.*g.*cos(vertGrade(j)) + 0.5.*Cd.*Af.*power(vehSpeed((j)),2).*rho_air + mass.*g.*sin(vertGrade(j))))./mass);
                    t1 = (vehSpeed(j+1)-vehSpeed(j))./newAcc(j);
                    s1 = (vehSpeed(j+1).^2-vehSpeed(j).^2)./(2.*newAcc(j));
                    if s1>distance(j)
                        t2 = 0;
                        s2 =0;
                        vehSpeed(j+1) = sqrt(2.*newAcc(j).*distance(j) + vehSpeed(j).^2);
                        t1 = (vehSpeed(j+1)-vehSpeed(j))./newAcc(j);
                        timeDelta(j) = t1;
                    else
                        t2 = (distance(j)-s1)./vehSpeed(j+1);
                        s2 = distance(j)-s1;
                        timeDelta(j+2:end+1) = timeDelta(j+1:end);
                        vehSpeed(j+2:end+1) = vehSpeed(j+1:end);
                        vertGrade(j+2:end+1) = vertGrade(j+1:end);
                        distance(j+2:end+1) = distance(j+1:end);
                        timeDelta(j) = t1;
                        timeDelta(j+1) = t2;
                        fr_new(j+2:end+1) = fr_new(j+1:end);
                        fr_new(j+1) = fr_new(j);
                        vehSpeed(j+1) = vehSpeed(j+2);
                        vertGrade(j+1) = vertGrade(j);
                        distance(j) = s1;
                        distance(j+1) = s2;
                        j=j+1;
                    end
                end
                j=j+1;

            end 
                            if any(timeDelta<0)
                    f = errordlg('Time going negative','Calculation Error');
                    return;
                elseif any(timeDelta>10)
                    f = errordlg('Time exceeding 10 seconds','File Error');
                    return;
                end
            app.LaptimesecconsideringtorquecurveEditField.Value = sum(timeDelta);
            vertGrade(find(timeDelta==0)) = [];
            vertGrade(find(vertGrade==NaN)) = 0;
            vertGrade = fillmissing(vertGrade,'previous')
            vehSpeed(find(timeDelta==0)) = [];
            fr_new(find(timeDelta==0)) = [];
            timeDelta(find(timeDelta==0)) = [];
            app.newVertGrade = interp1(cumsum(timeDelta),[vertGrade;vertGrade(end)],1:max(cumsum(timeDelta)));
            app.newgSpeed = interp1(cumsum(timeDelta),vehSpeed,1:max(cumsum(timeDelta)));
            app.newTime = 1:max(cumsum(timeDelta));
            app.fr_new = interp1(cumsum(timeDelta),fr_new,1:max(cumsum(timeDelta)));

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UITable.Data = sortrows(app.UITable.Data);
        end

        % Button pushed function: DrivecycleButton
        function DrivecycleButtonPushed(app, event)
            gSpeed = app.newgSpeed;
            vertGrade = app.newVertGrade(1:end-1);
            fr_new = app.fr_new
            time = app.newTime;
            acc = diff(gSpeed);
            acc(find(abs(acc)>=9.8*1.2))=1.2.*9.8;
            fr = 0.25;
            Cd = 0.4;
            Af = 5;
            rho_air = 1.224;
            g =9.81;
            mass = app.MasskgEditField.Value;
            eng_power = str2num(app.EnginePowerkWEditField.Value);
            gen_eff = app.GenEffEditField.Value./100;
            elec_power_peak = str2num(app.MaxDischargePowerkWEditField.Value);
            charge_power = app.MaxChargePowerkWEditField.Value;


            rr = fr_new(1:end-1).*mass.*g.*cos(vertGrade).*gSpeed(1:end-1);
            aero =  0.5.*Cd.*Af.*power(gSpeed(1:end-1),2).*rho_air.*gSpeed(1:end-1);
            grade = mass.*g.*sin(vertGrade).*gSpeed(1:end-1);
            force  = mass.*(acc) + fr_new(1:end-1).*mass.*g.*cos(vertGrade) + 0.5.*Cd.*Af.*power(gSpeed(1:end-1),2).*rho_air + mass.*g.*sin(vertGrade);
            instPower = force.*gSpeed(1:end-1)./1000;
            posPower = instPower;
            posPower(find(posPower<0)) = 0;
            posPower(find(isnan(posPower))) = 0;
            negPower = instPower;
            negPower(find(negPower>0)) = 0;
            negPower(find(isnan(negPower))) = 0;
            posEnergy = trapz(time(1:end-1),posPower);
            negEnergy = trapz(time(1:end-1),negPower);
            posPowerRef = posPower;
            timeDelta = ones(length(gSpeed),1)';
            timeDelta(1:4192) = ones(1,4192);
            newAcc = zeros(1,length(gSpeed));
            n=0;
            h =1;
            j= (eng_power.*gen_eff + elec_power_peak)./100;

            distance = gSpeed.*1 + 0.5.*[acc';0]'.*1;
            distanceRef = distance;
            force  = (mass.*(acc) + fr_new(1:end-1).*mass.*g.*cos(vertGrade) + 0.5.*Cd.*Af.*power(gSpeed(1:end-1),2).*rho_air + mass.*g.*sin(vertGrade));
            instPower = force.*gSpeed(1:end-1)./1000;
            vehSpeed = gSpeed;
            posPower = instPower;
            posPower(find(posPower<0)) = 0;

            rr = fr_new(1:end-1).*mass.*g.*cos(vertGrade).*gSpeed(1:end-1);
            aero =  0.5.*Cd.*Af.*power(gSpeed(1:end-1),2).*rho_air.*gSpeed(1:end-1);
            grade = mass.*g.*sin(vertGrade).*gSpeed(1:end-1);
            batt_energy_series = zeros(length(gSpeed),1);
            batt_energy_series(4192) = str2num(app.BatteryMaxCapacitykWhEditField.Value).*3.6e6;
            driveline_eff_series  = 0.97;
            mgu_eff_series_drive =0.9;
            chargeEnergy = 1000.*str2num(app.MaxChargePowerkWEditField.Value).*str2num(app.TimeforMaxChargingsecEditField.Value);
            batt_energy_time_lim = round(str2num(app.TimeforMaxChargingsecEditField.Value));
            chg_pow = 0;
            eng_pow_series = 0;
            % for powLimit = 400:50:600

            contDchgPower = str2num(app.ContDchgEditField.Value);
            
            for i = 4192:length(instPower)

                i;
                if i<(4192+31)
                    chg_pow(i) = 0;
                else
                    thermalEnergyBattery = 1000.*trapz(cumsum(timeDelta(i-batt_energy_time_lim:i)),abs(((posPower(i-batt_energy_time_lim:i)./(driveline_eff_series.*mgu_eff_series_drive))-eng_pow_series.*gen_eff)));
                    if thermalEnergyBattery>chargeEnergy
                        chg_pow(i) = -str2num(app.ContinousChargePowerkWEditField.Value);
                        elec_power_peak = str2num(app.MaxDischargePowerkWEditField.Value).*(contDchgPower/str2num(app.MaxDischargePowerkWEditField.Value));
                    else
                        chg_pow(i) = -str2num(app.MaxChargePowerkWEditField.Value);
                        elec_power_peak = str2num(app.MaxDischargePowerkWEditField.Value);
                    end
                end
                
                if(posPower(i)>j.*100)
                    newAcc(i)  = (j.*100000 - rr(i)-aero(i)-grade(i))./(mass.*vehSpeed(i));
                    %         disp(newAcc(i))
                    %         timeDelta(i) = (vehSpeed(i+1)-vehSpeed(i))./newAcc(i);
                    syms t
                    eqn = distance(i)==vehSpeed(i).*t + 0.5*newAcc(i).*t.^2;
                    soln = double(solve(eqn));
                    timeDelta(i) = min(soln(find(soln>0)));
                    vcheck = vehSpeed(i) + newAcc(i).*timeDelta(i);
                    force1  = mass.*(newAcc(i)) + fr_new(i).*mass.*g.*cos(vertGrade(i)) + 0.5.*Cd.*Af.*power(vehSpeed((i)),2).*rho_air + mass.*g.*sin(vertGrade(i));
                    posPower(i) = force1.*vehSpeed(i)./1000;
                    if vcheck<vehSpeed(i+1)
                        vehSpeed(i+1) = vcheck;
                        %                 posPower(i) = force1.*vehSpeed(i+1)./1000;
                        accUpdate = vehSpeed(i+2)-vehSpeed(i+1);
                        posPower(i+1) = (mass.*(accUpdate) + fr_new(i+1).*mass.*g.*cos(vertGrade(i+1)) + 0.5.*Cd.*Af.*power(gSpeed((i+1)),2).*rho_air + mass.*g.*sin(vertGrade(i+1))).*gSpeed(i+1)./1000;
                        rr(i+1) = fr_new(i+1).*mass.*g.*cos(vertGrade(i+1)).*vehSpeed(i+1);
                        aero(i+1) =  0.5.*Cd.*Af.*power(vehSpeed(i+1),2).*rho_air.*vehSpeed(i+1);
                        grade(i+1) = mass.*g.*sin(vertGrade(i+1)).*vehSpeed(i+1);
                    end
                elseif (posPower(i)<posPowerRef(i)  && instPower(i)>0 && vehSpeed(i)>10)
                    if(posPowerRef(i)<j.*100)
                        newAcc(i)  = (posPowerRef(i).*1000 - rr(i)-aero(i)-grade(i))./(mass.*vehSpeed(i));
                    else
                        newAcc(i)  = (j.*100000 - rr(i)-aero(i)-grade(i))./(mass.*vehSpeed(i));
                    end
                    if newAcc(i)>1.18.*9.8
                        newAcc(i) = 1.18.*9.8;
                    end
                    force1  = mass.*(newAcc(i)) + fr_new(i).*mass.*g.*cos(vertGrade(i)) + 0.5.*Cd.*Af.*power(vehSpeed((i+1)),2).*rho_air + mass.*g.*sin(vertGrade(i));
                    posPower(i) = force1.*vehSpeed((i))./1000;
                    syms t
                    eqn = distance(i)==vehSpeed(i).*t + 0.5*newAcc(i).*t.^2;
                    soln = double(solve(eqn));
                    timeDelta(i) = min(soln(find(soln>0)));
                    %         disp(timeDelta(i))
                elseif(vehSpeed(i) ~=gSpeed(i))
                    accUpdate2 = vehSpeed(i+1)-vehSpeed(i);
                    syms t
                    eqn = distance(i)==vehSpeed(i).*t + 0.5*accUpdate2*t.^2;
                    soln = double(solve(eqn));
                    timeDelta(i) = min(soln(find(soln>0)));
                else
                    timeDelta(i) =1;
                end

                eng_pow_series = str2num(app.EnginePowerkWEditField.Value);
                batt_energy_series(i+1) = min(max(batt_energy_series(i) - (max(((posPower(i)./(driveline_eff_series.*mgu_eff_series_drive))-eng_pow_series.*gen_eff),chg_pow(i)).*1000.*timeDelta(i)).*1,0),batt_energy_series(4192));
                chg_log(i) = max(((posPower(i)./(driveline_eff_series.*mgu_eff_series_drive))-eng_pow_series.*gen_eff),chg_pow(i));
                if batt_energy_series(i+1) <= batt_energy_series(4192).*0.1
                    j = (eng_pow_series.*gen_eff)./100;
                else
                    j = (eng_pow_series.*gen_eff + elec_power_peak)./100;
                end


                % If aero force is greater, decelerate and do distance based
                % calculations
            end
            app.LaptimesecconsideringpowerandenergyEditField.Value = sum(timeDelta);

    
             % Prompt the user to choose the save location and file name
    [filename, path] = uiputfile('*.mat', 'Save Variables as .mat File');

    % If the user cancels the file selection, uiputfile returns 0
    if isequal(filename, 0) || isequal(path, 0)
        return; % Exit the function without saving if the user cancels
    end

    % Combine the file name and path
    filepath = fullfile(path, filename);

    % Save the variables to the .mat file
    save(filepath, 'vehSpeed', 'timeDelta','vertGrade','fr_new');

    % Display a message to the user indicating the save was successful
    msgbox('Variables saved successfully!', 'Save Complete', 'modal');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.GenEffEditFieldLabel = uilabel(app.InputsPanel);
            app.GenEffEditFieldLabel.Text = 'Gen Eff %';
            app.GenEffEditField = uieditfield(app.InputsPanel, 'numeric');
            app.GenEffEditField.Value = 95;
            app.MaxMotorTorqueEditFieldLabel = uilabel(app.InputsPanel);
            app.MaxMotorTorqueEditFieldLabel.Text = 'Max Motor Torque';
            app.MaxMotorTorqueEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MaxMotorTorqueEditField.Value = 500;
            app.CurrentatMaxTorqueEditFieldLabel = uilabel(app.InputsPanel);
            app.CurrentatMaxTorqueEditFieldLabel.Text = 'Current at Max Torque';
            app.CurrentatMaxTorqueEditField = uieditfield(app.InputsPanel, 'numeric');
            app.CurrentatMaxTorqueEditField.Value = 500;
            app.BatteryMaxCurrentEditFieldLabel = uilabel(app.InputsPanel);
            app.BatteryMaxCurrentEditFieldLabel.Text = 'Battery Max Current';
            app.UITable = uitable(app.InputsPanel);
            app.UITable.ColumnName = {'RPM'; 'Torque'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.BatteryMaxCurrentEditField = uieditfield(app.InputsPanel, 'numeric');
            app.BatteryMaxCurrentEditField.Value = 500;
            app.ContDchgEditFieldLabel = uilabel(app.InputsPanel);
            app.ContDchgEditFieldLabel.Text = 'Cont. Dchg';
            app.ContDchgEditField = uieditfield(app.InputsPanel, 'text');
            app.Assuming5050DistCoG40inwheelbase130inGrade40Label = uilabel(app.UIFigure);
            app.Assuming5050DistCoG40inwheelbase130inGrade40Label.Text = 'These values are just to calculate the FDR, The drivecyle only considers longitudnal performance';
            app.BatteryMaxCapacitykWhEditFieldLabel = uilabel(app.UIFigure);
            app.BatteryMaxCapacitykWhEditFieldLabel.Text = 'Battery Max Capacity (kWh)';
            app.BatteryMaxCapacitykWhEditField = uieditfield(app.UIFigure, 'text');
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasskgEditField.Value = 2600;
            app.MaxChargePowerkWEditFieldLabel = uilabel(app.UIFigure);
            app.MaxChargePowerkWEditFieldLabel.Text = 'Max Charge Power (kW)';
            app.MaxChargePowerkWEditField = uieditfield(app.UIFigure, 'text');
            app.MaxChargePowerkWEditField.Value = '200';
            app.DistribuitionRearEditFieldLabel = uilabel(app.UIFigure);
            app.DistribuitionRearEditFieldLabel.Text = 'Distribuition Rear %';
            app.DistribuitionRearEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistribuitionRearEditField.Value = 50;
            app.TimeforMaxChargingsecEditFieldLabel = uilabel(app.UIFigure);
            app.TimeforMaxChargingsecEditFieldLabel.Text = 'Time for Max Charging (sec)';
            app.TimeforMaxChargingsecEditField = uieditfield(app.UIFigure, 'text');
            app.TimeforMaxChargingsecEditField.Value = '30';
            app.CoGheightinEditFieldLabel = uilabel(app.UIFigure);
            app.CoGheightinEditFieldLabel.Text = 'CoG height (in)';
            app.CoGheightinEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoGheightinEditField.Value = 40;
            app.ContinousChargePowerkWEditFieldLabel = uilabel(app.UIFigure);
            app.ContinousChargePowerkWEditFieldLabel.Text = 'Continous Charge Power (kW)';
            app.ContinousChargePowerkWEditField = uieditfield(app.UIFigure, 'text');
            app.ContinousChargePowerkWEditField.Value = '50';
            app.CoGheightinEditFieldLabel_2 = uilabel(app.UIFigure);
            app.CoGheightinEditFieldLabel_2.Text = 'Wheelbase  (m)';
            app.wheelbaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.wheelbaseEditField.Value = 130;
            app.MaxDischargePowerkWEditFieldLabel = uilabel(app.UIFigure);
            app.MaxDischargePowerkWEditFieldLabel.Text = 'Max Discharge Power (kW)';
            app.MaxDischargePowerkWEditField = uieditfield(app.UIFigure, 'text');
            app.MaxDischargePowerkWEditField.Value = '200';
            app.GradedegEditFieldLabel = uilabel(app.UIFigure);
            app.GradedegEditFieldLabel.Text = 'Grade (deg)';
            app.GradedegEditField = uieditfield(app.UIFigure, 'numeric');
            app.GradedegEditField.Value = 30;
            app.EnginePowerkWEditFieldLabel = uilabel(app.UIFigure);
            app.EnginePowerkWEditFieldLabel.Text = 'Engine Power (kW)';
            app.EnginePowerkWEditField = uieditfield(app.UIFigure, 'text');
            app.EnginePowerkWEditField.Value = '200';
            app.FinalDriveRatioLabel = uilabel(app.UIFigure);
            app.FinalDriveRatioLabel.Text = 'Final Drive Ratio ';
            app.FinalDriveRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalDriveRatioEditField.Editable = 'off';
            app.TopSpeedmphEditFieldLabel = uilabel(app.UIFigure);
            app.TopSpeedmphEditFieldLabel.Text = 'Top Speed (mph)';
            app.TopSpeedmphEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopSpeedmphEditField.Editable = 'off';
            app.CalculateFDRButton = uibutton(app.UIFigure, 'push');
            app.CalculateFDRButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFDRButtonPushed, true);
            app.CalculateFDRButton.Text = 'Calculate FDR';
            app.LaptimesecconsideringtorquecurveEditFieldLabel = uilabel(app.UIFigure);
            app.LaptimesecconsideringtorquecurveEditFieldLabel.Text = 'Lap time (sec) considering torque curve';
            app.LaptimesecconsideringtorquecurveEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaptimesecconsideringtorquecurveEditField.ValueDisplayFormat = '%.1f';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.DrivecycleButton = uibutton(app.UIFigure, 'push');
            app.DrivecycleButton.ButtonPushedFcn = createCallbackFcn(app, @DrivecycleButtonPushed, true);
            app.DrivecycleButton.Text = 'Drivecycle';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.LaptimesecconsideringpowerandenergyEditFieldLabel = uilabel(app.UIFigure);
            app.LaptimesecconsideringpowerandenergyEditFieldLabel.Text = 'Lap time (sec) considering power and energy';
            app.LaptimesecconsideringpowerandenergyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaptimesecconsideringpowerandenergyEditField.ValueDisplayFormat = '%.1f';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drivecyclecreator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end